classdef DIP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        DropDown           matlab.ui.control.DropDown
        DropDownLabel      matlab.ui.control.Label
        UploadImageButton  matlab.ui.control.Button
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
if isempty(app.UIFigure.UserData)
    return;
end

img = app.UIFigure.UserData;
img = im2double(img);


            style = app.DropDown.Value;

            switch style

case 'Pencil Sketch'

    gray = im2double(rgb2gray(img));

    % Invert image
    invImg = 1 - gray;

    % Strong blur (pencil shading control)
    blurImg = imgaussfilt(invImg, 8);

    % Dodge blend (classic pencil sketch formula)
    output = gray ./ (1 - blurImg + 0.001);

    % Normalize
    output = mat2gray(output);

    % Enhance contrast
    output = imadjust(output, stretchlim(output), []);

    % Convert to white background sketch
    output = 1 - output;

    % Remove noise / refine lines
    output = imgaussfilt(output, 0.5);            
                case 'Ink Outline'

    gray = rgb2gray(img);

    % Edge detection
    edges = edge(gray,'Canny',[0.08 0.2]);

    % Thicken edges
    edges = imdilate(edges, strel('disk',1));

    % Create white background
    output = uint8(255 * ones(size(img)));

    % Apply black lines on all channels
    for c = 1:3
        channel = output(:,:,c);
        channel(edges) = 0;
        output(:,:,c) = channel;
    end
case 'Oil Painting'
                    % High accuracy structural smoothing using domain filtering
                    smoothed = imbilatfilt(img, 0.15, 5); 
                    
                    % Quantize colors to mimic distinct oil paint palettes
                    numBins = 8;
                    quantized = round(smoothed * (numBins - 1)) / (numBins - 1);
                    
                    % Run a localized statistical modal filter to form cohesive paint blobs
                    output = quantized;
                    for c = 1:3
                        output(:,:,c) = medfilt2(quantized(:,:,c), [5 5], 'symmetric');
                    end
                    
                    % Sharpen artistic brush edges softly
                    output = imsharpen(output, 'Radius', 1.5, 'Amount', 1.2);
                    output = min(max(output, 0), 1); % Safe color bounds clip

case 'Charcoal Sketch'
                    % NAYA EFFECT: Rich Gothic Charcoal Drawing
                    gray = rgb2gray(img);
                    
                    % Texture Extraction using local standard deviation (FIXED TYPO)
%  To this:
se = strel('disk', 3);
texture = stdfilt(gray, se.Neighborhood);                    texture = mat2gray(texture);
                    
                    % Edge tracing
                    edges = edge(gray, 'Canny', [0.05 0.15]);
                    edges = imdilate(edges, strel('disk', 1));
                    
                    % Blend smudge details with deep shadows
                    charcoal = (1 - gray) .* 1.5 + (texture * 0.5);
                    charcoal = 1 - charcoal; % Invert to make paper white and charcoal dark
                    
                    % Inject strong dark strokes on details
                    charcoal(edges) = charcoal(edges) * 0.3;
                    
                    % Contrast Enhancement for bold artistic feel
                    charcoal = imadjust(charcoal, [0.1 0.8], [0 1]);
                    
                    % Create 3-channel gray canvas
                    output = cat(3, charcoal, charcoal, charcoal);
                    output = min(max(output, 0), 1);      
                case 'Pop Art'

    img = im2double(img);

    % Reduce colors (poster effect)
    levels = 5;
    quant = floor(img * levels) / levels;

    % Convert to grayscale for edges
    gray = rgb2gray(img);

    % Detect edges
    edges = edge(gray,'Canny',0.1);

    % Thicken edges
    edges = imdilate(edges, strel('disk',1));

    % Start with poster colors
    output = quant;

    % Add black outlines
    for c = 1:3
        channel = output(:,:,c);
        channel(edges) = 0;
        output(:,:,c) = channel;
    end

    % Boost contrast
end   % <-- THIS closes switch       output = imadjust(output, stretchlim(output), []);

            imshow(output,'Parent',app.UIAxes2);  
        end

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg'});

            if isequal(file,0)
                return;
            end

            img = imread(fullfile(path,file));

            app.UIFigure.UserData = img;% save image

            imshow(img,'Parent',app.UIAxes);
            
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Preview')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Final Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Upload Image';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Pencil Sketch', 'Ink Outline', 'Oil Painting', 'Charcoal Sketch', 'Pop Art'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Pencil Sketch';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DIP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end